classdef TOF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoltageMeter                   matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConnectionTab                  matlab.ui.container.Tab
        DeviceListDropDownLabel        matlab.ui.control.Label
        DeviceListDropDown             matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        WaitforconnectionLabel         matlab.ui.control.Label
        ConfigurationTab               matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        SendandreceivePanel            matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        NumberofpulsetosendEditFieldLabel  matlab.ui.control.Label
        NumberofpulsetosendEditField   matlab.ui.control.NumericEditField
        NumberofpulsetoreceiveEditFieldLabel  matlab.ui.control.Label
        NumberofpulsetoreceiveEditField  matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        MaxofTimeofFlightusDropDownLabel  matlab.ui.control.Label
        MaxofTimeofFlightusDropDown    matlab.ui.control.DropDown
        TriggerPanel                   matlab.ui.container.Panel
        GatingTimeusEditFieldLabel     matlab.ui.control.Label
        GatingTimeusEditField          matlab.ui.control.NumericEditField
        TriggerThresholdmVEditFieldLabel  matlab.ui.control.Label
        TriggerThresholdmVEditField    matlab.ui.control.NumericEditField
        mV62mVLabel                    matlab.ui.control.Label
        usNN3216Label                  matlab.ui.control.Label
        UploadParametersButton         matlab.ui.control.Button
        UIAxes2_2                      matlab.ui.control.UIAxes
        DoneLabel                      matlab.ui.control.Label
        iTOFMeasurementsTab            matlab.ui.container.Tab
        CaptureButton_2                matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        TemperatureLabel               matlab.ui.control.Label
        idleLabel                      matlab.ui.control.Label
        dTOFMeasurementsTab            matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        TemperatureLabel_2             matlab.ui.control.Label
        dTOFmeasurementsButton         matlab.ui.control.Button
        BuildReferenceButton           matlab.ui.control.Button
        ReferencesignalisnotavailableLabel  matlab.ui.control.Label
        idleLabel_2                    matlab.ui.control.Label
        CalibrationtemperatureTab      matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        CalibrationButton              matlab.ui.control.Button
        TOFvsTimeTab                   matlab.ui.container.Tab
        UIAxes_5                       matlab.ui.control.UIAxes
        CaptureButton_3                matlab.ui.control.Button
        STOPButton_2                   matlab.ui.control.Button
        ClearButton_2                  matlab.ui.control.Button
        DebugButton_2                  matlab.ui.control.Button
        autoscaleButton                matlab.ui.control.Button
        TemperaturecalibrationTab      matlab.ui.container.Tab
        CaptureButton_4                matlab.ui.control.Button
        STOPButton_3                   matlab.ui.control.Button
        UIAxes_6                       matlab.ui.control.UIAxes
        autoscaleButton_2              matlab.ui.control.Button
        ClearButton_3                  matlab.ui.control.Button
        DeviceListDropDown_2Label      matlab.ui.control.Label
        DeviceListDropDown_2           matlab.ui.control.DropDown
        ConnecttoreftempsensorButton   matlab.ui.control.Button
        FindsensorButton               matlab.ui.control.Button
        WaitforconnectionLabel_2       matlab.ui.control.Label
        refLabel                       matlab.ui.control.Label
        PT1000Label                    matlab.ui.control.Label
        CalibrationTDCTab              matlab.ui.container.Tab
        CalibrationofTDCPanel          matlab.ui.container.Panel
        GatingTimeusEditField_4Label   matlab.ui.control.Label
        GatingTimeusEditField_4        matlab.ui.control.NumericEditField
        TriggerThresholdmVEditField_4Label  matlab.ui.control.Label
        TriggerThresholdmVEditField_4  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
       
       Continue;  % continue capturing
       Maxpoints=50000; % max sampling depth
       Voltage_per_bit=3.42;
       current_ratio=1.9963;
       
       bufferSizeA = 1e6;  % Large buffer for storing all incoming data
       bufferSizeB = 500; % Small buffer for plotting
     
       
       bufferIndexA = 1;  % Write index for buffer A
       bufferIndexB = 1;  % Write index for buffer B
       % register0
       sel_tsto1 = 0;
       sel_tsto2 = 0;
       
       sel_timo_mb=2;
       hitin=9;
      
       neg_stop=0;
       neg_start=0; 
       start_clkhs=2;
       div_clkhs=0;
       div_fire=3;
       anz_fire=8;
       
       %register1
       offset=0;
       DELVAL1=120;
       RFEDGE=0;
       EN_INT_ALU=1;
       EN_INT_HIT=0;
       EN_INT_TO=0;


       % temperature cal
       R0_calibration=1000;
       
       % temperature compensation
       TOF_over_R=0.2667;
       Temperature_over_R=0.01;
       
       % reference R
       R_reference=1100;


    end
    
    properties (Access = public)
        time; 
        A;
        B;
        C;
       % buffer for channels
       bufferA=[];
       %buffer for plotting
       bufferB=[];
       row_index=[];
        A_mean=2036;
        B_mean=2045;
        % Description
         % TOF Data
       rawData;
       InstrumentObject ; % the serial port that retrieve the data
       reference_temp_sensor;  
    end


    methods (Access = private)
        
        function ChannelRegister(app)
            if (app.A0ChannelCheckBox.Value==1)
               app.Channel0P=0;
               app.Channel0N=1;
           else
               app.Channel0P=[];
               app.Channel0N=[];
            end
            
            if (app.A1ChannelCheckBox.Value==1)
               app.Channel1P=16*2;
               app.Channel1N=3;
           else
               app.Channel1P=[];
               app.Channel1N=[];
            end
            
            if (app.A2ChannelCheckBox.Value==1)
               app.Channel2P=16*4;
               app.Channel2N=5;
           else
               app.Channel2P=[];
               app.Channel2N=[];
            end
            
            if (app.A3ChannelCheckBox.Value==1)
               app.Channel3P=16*6;
               app.Channel3N=7;
           else
               app.Channel3P=[];
               app.Channel3N=[];
            end
            
         end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         % Find all available instruments
           ports=seriallist;
         % initial all buffers
%            app.bufferA = NaN(4,app.bufferSizeA);
%            app.bufferB = NaN(4,app.bufferSizeB);
           app.DeviceListDropDown.Items=ports; 
%            set(app.UIAxes,'linewidth',1,'FontSize',20)
%            app.UIAxes.XLabel.String='Counts';
%            app.UIAxes.YLabel.String='Voltage (V)';
           
           
           set(app.UIAxes_5,'linewidth',1,'FontSize',20)
           app.UIAxes_5.XLabel.String='Time (s)';
           app.UIAxes_5.YLabel.String='TOF (ns)';
           
           yyaxis(app.UIAxes_5,'right')
           app.UIAxes_5.YLabel.String='Temperature (^0 C)';
           
           
           imshow('TOF.jpg', 'Parent', app.UIAxes2)
           imshow('trigger.png', 'Parent', app.UIAxes2_2)


           % yyaxis(app.UIAxes,'right')
           % app.UIAxes.YLabel.String='Current (A)';
           
           %  assignin('base', 'TOF', app);
           
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Get the selected item from the dropdown
            
            obj=serial(app.DeviceListDropDown.Value,'BaudRate',4800);
            app.InstrumentObject=obj;
            
            set(app.InstrumentObject, 'InputBufferSize', 5000000);
           % Open the serial port
            fopen(app.InstrumentObject);
            app.WaitforconnectionLabel.Text='Connected';
            
        end

        % Close request function: VoltageMeter
        function VoltageMeterCloseRequest(app, event)
           
            
            s= instrfind;
           if isempty(s)
           else
                % send reset signal
            fwrite(app.InstrumentObject,0,'uint8');
           fclose(s);
           delete(s);
            clear s;
           end
            delete(app)
            
        end

        % Callback function
        function CaptureButtonPushed(app, event)
            % stop the capture action
            STOPButtonPushed(app, event);
            app.NumberOfChannels=app.A0ChannelCheckBox.Value+app.A1ChannelCheckBox.Value+app.A2ChannelCheckBox.Value+app.A3ChannelCheckBox.Value;
            % 1st byte, gain or voltage range
            % 2nd byte, data rate
            % 3rd, number of selected channels
            % 4th-11th bytes, registers for channels as uint16, if a channel is unselected, channel_x will be assign to empty
            % 12 byte, 1 for buffer on, 0 for buffer off
            app.ChannelRegister();
            configuration_register = [uint8(str2double(app.VoltageRangeDropDown.Value)), uint8(str2double(app.SamplingRateDropDown_2.Value)), uint8(app.NumberOfChannels), uint8(app.Channel0P),uint8(app.Channel0N),uint8(app.Channel1P),uint8(app.Channel1N),uint8(app.Channel2P),uint8(app.Channel2N),uint8(app.Channel3P), uint8(app.Channel3N),uint8(app.EnableBufferCheckBox.Value)]; % Array of uint8 values
            % filter out the empty bytes
            configuration_register = configuration_register(~cellfun(@isempty, num2cell(configuration_register)));
            % send the filtered array to stm32
            fwrite(app.InstrumentObject,configuration_register,'uint8');
            % set continue as 1
            app.Continue=1;
            
             % number of capturing channels
             app.row_index=zeros(1,configuration_register(3));
             for i=1:configuration_register(3)
                 % calculate the buffer index
                 app.row_index(i)=configuration_register(2+2*i)/32+1;
             end
            
            
            while (app.Continue==1)
               
               if  app.InstrumentObject.BytesAvailable>4
               rawData = fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable, "uint8");
        
                % Convert 4 bytes to float
               % if mod(length(rawData),4)~=0
                %   app.slowerforhighaccuracyLabel.Text='loss data';
                % end
                
                numFloats = floor(length(rawData) / 4);
                % keyboard
                floatData =reshape( typecast(uint8(rawData(1:numFloats*4)), 'single'),double(configuration_register(3)),numFloats/double(configuration_register(3)));


                % Store in Buffer A using Circular Indexing
                 numNewData = numFloats/double(configuration_register(3));
                 endIndexA = app.bufferIndexA + numNewData - 1;


        if endIndexA <= app.bufferSizeA
            app.bufferA(app.row_index,app.bufferIndexA:endIndexA) = floatData;
        else
            splitPoint = app.bufferSizeA - app.bufferIndexA + 1;
            app.bufferA(app.row_index,app.bufferIndexA:end) = floatData(1:splitPoint);
            app.bufferA(app.row_index,1:numNewData - splitPoint) = floatData(splitPoint + 1:end);
        end
        
        % Update bufferIndexA (circular wrap-around)
        app.bufferIndexA = mod(app.bufferIndexA + numNewData - 1, app.bufferSizeA) + 1;
        
        % Select 1 point from bufferA to store in bufferB (Downsampling)
        app.bufferB(app.row_index,app.bufferIndexB) = floatData(:,end);  % Pick latest value
        % demostrate the conversion result in the text
        
        % Update bufferIndexB (circular wrap-around)
        app.bufferIndexB = mod(app.bufferIndexB, app.bufferSizeB) + 1;
        
        
        % Update plot
        for i=1:app.NumberOfChannels
         plot(app.UIAxes, app.bufferB(app.row_index(i),:));
         hold(app.UIAxes,'on')
         
        end
             
          hold(app.UIAxes,'off')   
               end   
                pause(0.001)  
              drawnow
             
            end 


%             app.Continue=0; 
%             % flush input buffer
%             if (app.InstrumentObject.BytesAvailable>0)
%                 fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
%             end
%             
%             value = app.A0VEditField.Value;
%             if (value>1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3)
%                 app.A0VEditField.Value=1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3;
%             end
%             
%             
%             cla(app.UIAxes) 
%             sysfrequency=125;
%             period=1/(str2double(app.VoltageRangeDropDown.Value)*1e6);
%             div=sysfrequency/str2double(app.VoltageRangeDropDown.Value);
%             length_of_buf= uint16(app.A0VEditField.Value*1e-3*str2double(app.VoltageRangeDropDown.Value)*1e6);
%              
%             fwrite(app.InstrumentObject,div,'uint8');
%             fwrite(app.InstrumentObject,length_of_buf,'uint16');
%             
%             dataStr = fscanf(app.InstrumentObject, '%s');  % Read all incoming data as a string
%             dataLen=length(dataStr);
%             % Split the string into 8-character groups (each representing one uint32)
%             numGroups = dataLen / 8;
%             receivedData = reshape(dataStr, 8, numGroups)';  % Reshape into a matrix where each row is 8 characters
% 
%            % Convert each group of 8 characters from hex string to uint32
%            uint32Values = uint32(hex2dec(receivedData));
%            % Initialize arrays to store the split data
%            
%            [app.A, app.B] = app.extractData(uint32Values);
%            app.time=0:period:period*double((length_of_buf-1));
%           
%           
%            x=app.time;
%            yyaxis(app.UIAxes,'left') 
%            plot(app.UIAxes,x,double(app.A)-app.A_mean)
%            hold(app.UIAxes,'on')
%            yyaxis(app.UIAxes,'right')
%            plot(app.UIAxes,x,double(app.B)-app.B_mean)
%            hold(app.UIAxes,'off')


        end

        % Callback function
        function A0VEditFieldValueChanged(app, event)
           
            value = app.A0VEditField.Value;
            if (value>1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3)
                app.A0VEditField.Value=1/(str2double(app.VoltageRangeDropDown.Value)*1e6)*app.Maxpoints*1e3;
            end
                
        end

        % Callback function
        function AutoButtonPushed(app, event)
            if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
            end
            
            
            sysfrequency=125;
            period=1/(str2double(app.VoltageRangeDropDown.Value)*1e6);
            div=sysfrequency/str2double(app.VoltageRangeDropDown.Value);
            length_of_buf= uint16(512);
            app.Continue=1;
            while (app.Continue==1)
                fwrite(app.InstrumentObject,div,'uint8');
                fwrite(app.InstrumentObject,length_of_buf,'uint16');
                
                dataStr = fscanf(app.InstrumentObject, '%s');  % Read all incoming data as a string
                dataLen=length(dataStr);
                % Split the string into 8-character groups (each representing one uint32)
                numGroups = dataLen / 8;
                receivedData = reshape(dataStr, 8, numGroups)';  % Reshape into a matrix where each row is 8 characters
                
                % Convert each group of 8 characters from hex string to uint32
                uint32Values = uint32(hex2dec(receivedData));
                % Initialize arrays to store the split data
                
                [app.A, app.B] = app.extractData(uint32Values);
                app.time=0:period:period*double((length_of_buf-1));
                
                
                x=app.time;
                cla(app.UIAxes)
                yyaxis(app.UIAxes,'left')
                plot(app.UIAxes,x,double(app.A)-app.A_mean)
                hold(app.UIAxes,'on')
                yyaxis(app.UIAxes,'right')
                plot(app.UIAxes,x,double(app.B)-app.B_mean)
                hold(app.UIAxes,'off')
                pause(0.1); drawnow;
                 
                  if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
                  end
           
            end
            
                
        end

        % Callback function
        function AutoScaleButtonPushed(app, event)
            axis(app.UIAxes, 'tight'); 
        end

        % Callback function
        function DebugButtonPushed(app, event)
             keyboard
        end

        % Callback function
        function A1ChannelCheckBoxValueChanged(app, event)
            value = app.A1ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=32;
               app.Channel1N=3;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Callback function
        function A2ChannelCheckBoxValueChanged(app, event)
            value = app.A2ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=16*4;
               app.Channel1N=5;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Callback function
        function A3ChannelCheckBoxValueChanged(app, event)
            value = app.A3ChannelCheckBox.Value;
            if (value==1)
               app.Channel1P=16*6;
               app.Channel1N=7;
           else
               app.Channel1P=[];
               app.Channel1N=[];
           end
        end

        % Callback function
        function STOPButtonPushed(app, event)
            % write 3 0 bytes to stm32 to stop capturing
            fwrite(app.InstrumentObject, [0, 0, 0], "uint8");
            % set continue as 0
            app.Continue=0;
             if (app.InstrumentObject.BytesAvailable>0)
                fread(app.InstrumentObject, app.InstrumentObject.BytesAvailable);
             end
        end

        % Callback function
        function ClearButtonPushed(app, event)
            %clear 
             app.bufferIndexA = 1;  % Write index for buffer A
             app.bufferIndexB = 1;
             app.bufferA = NaN(4,app.bufferSizeA);
             app.bufferB = NaN(4,app.bufferSizeB);
             
        end

        % Value changed function: NumberofpulsetosendEditField
        function NumberofpulsetosendEditFieldValueChanged(app, event)
            value = floor(app.NumberofpulsetosendEditField.Value);
            
            if value>127
                value=127;
            end
            
            if value<1
                value=1;
            end
            
            app.NumberofpulsetosendEditField.Value=value;
            
            app.anz_fire=value;
        end

        % Callback function
        function DrivingfrequencyMHzEditFieldValueChanged(app, event)
            value = app.DrivingfrequencyMHzEditField.Value;
            
            
        end

        % Value changed function: NumberofpulsetoreceiveEditField
        function NumberofpulsetoreceiveEditFieldValueChanged(app, event)
            value = floor(app.NumberofpulsetoreceiveEditField.Value);
            
             if value>8
                value=8;
            end
            
            if value<1
                value=1;
            end
            
            app.NumberofpulsetoreceiveEditField.Value=value;
            
            app.hitin=value+1;


        end

        % Value changed function: MaxofTimeofFlightusDropDown
        function MaxofTimeofFlightusDropDownValueChanged(app, event)
            value = app.MaxofTimeofFlightusDropDown.Value;
            app.sel_timo_mb=value;
        end

        % Value changed function: GatingTimeusEditField
        function GatingTimeusEditFieldValueChanged(app, event)
            value = app.GatingTimeusEditField.Value;
            
            if value>2^16-1
                value=2^16-1;
            end
            
            if value<0.75
                value=0.75;
            end
            
            app.GatingTimeusEditField.Value=value;
            app.DELVAL1=floor(value/0.25);
            
        end

        % Value changed function: TriggerThresholdmVEditField
        function TriggerThresholdmVEditFieldValueChanged(app, event)
            value = app.TriggerThresholdmVEditField.Value;
            
            if value>62
                value=62;
            end
            
            if value<-64
                value=-64;
            end
            
            app.TriggerThresholdmVEditField.Value=value;
            
             if value >= 0
        % 正电压: 0-62 mV -> 编码 0-31
        app.offset = floor(value / 2);
    else
        % 负电压: -64 mV 到 -2 mV -> 编码 32-63
        app.offset = floor((value / 2) + 64);
             end
        end

        % Button pushed function: CaptureButton_2
        function CaptureButton_2Pushed(app, event)
            % update the label
             app.idleLabel.Text='Capturing in process';
            % trigger tof measurements
            fwrite(app.InstrumentObject,2,'uint8');
            % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<8000)
                pause(1);
            end
            
            % retrieve the tof data
          app.rawData=fread(app.InstrumentObject,2000,'uint32')/2^16*250;
           
            % wait enough temperature data
            while (app.InstrumentObject.BytesAvailable<4)
                pause(1);
            end
 
            
            % 获取温度
             resistance=fread(app.InstrumentObject,1,'float');
             
%              % 获取pcb上温度传感器的数据
%              resistance_pcb=fread(app.InstrumentObject,1,'float');
%              
%              % 依据pcb的温度进一步补偿, tof rawData
%              app.rawData=app.rawData+app.TOF_over_R*(app.R_reference-resistance_pcb);
%              % 依据pcb的温度进一步补偿, 液体中的温度传感器
%              resistance=resistance+app.Temperature_over_R*(app.R_reference-resistance_pcb);
             
             
             temperature = pt1000_to_temperature(resistance, app.R0_calibration);


             app.TemperatureLabel.Text=num2str(temperature);


            h = histogram(app.UIAxes_2, app.rawData, 'FaceColor', 'blue', 'FaceAlpha', 0.7,'NumBins',50);
            
            % 计算统计量
            mean_val = mean(app.rawData);
            std_val = std(app.rawData);
            
            
            % 设置标题，显示统计信息
            title(app.UIAxes_2, sprintf('TOF distribution - Mean: %.2f, STD: %.2f', mean_val, std_val));
             
             
             % update to idle
             app.idleLabel.Text='idle';


        end

        % Button pushed function: UploadParametersButton
        function UploadParametersButtonPushed(app, event)
            app.DoneLabel.Text='Upload parameters';
            
            
            % form the two registers
            
            
            reg0 = build_write_reg0_param(app.sel_tsto1, app.sel_tsto2, app.sel_timo_mb, app.hitin, ...
                                     app.neg_stop, app.neg_start, app.start_clkhs, app.div_clkhs, ...
                                     app.div_fire, app.anz_fire);
            reg1 = build_write_reg1_param(app.offset, app.DELVAL1, app.RFEDGE, app.EN_INT_ALU, app.EN_INT_HIT, app.EN_INT_TO);
            
            %trigger the upload
            fwrite(app.InstrumentObject,1,'uint8');
            % write to mcu
            fwrite(app.InstrumentObject,reg0,'uint32');
            fwrite(app.InstrumentObject,reg1,'uint32');
            
            pause(1);
            app.DoneLabel.Text='Done';


        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            % send 3 trigger caliberation
            fwrite(app.InstrumentObject,3,'uint8');
             % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<800)
                pause(1);
            end
            
            % retrieve the resistance
            app.rawData=fread(app.InstrumentObject,200,'float');
            temperature=zeros(length(app.rawData),1);
            % compute the temperature
            for i=1:length(app.rawData)
                temperature(i) = pt1000_to_temperature(app.rawData(i), app.R0_calibration);
            
            end
            
            h = histogram(app.UIAxes_3, temperature, 'FaceColor', 'blue', 'FaceAlpha', 0.7,'NumBins',50);


            % 计算统计量
            mean_val = mean(temperature);
            std_val = std(temperature);
            
            
            % 设置标题，显示统计信息
            title(app.UIAxes_3, sprintf('Temperature distribution - Mean: %.2f, STD: %.4f', mean_val, std_val));
            
        end

        % Button pushed function: dTOFmeasurementsButton
        function dTOFmeasurementsButtonPushed(app, event)
            % update text flag
            app.idleLabel_2.Text='Capturing in process';
            
            % send 4 trigger dTOF measurements
            fwrite(app.InstrumentObject,4,'uint8');
            
            % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<8000)
                pause(1);
            end
            
            % retrieve the tof data
            app.rawData=fread(app.InstrumentObject,2000,'int32')/2^16*250;
            h = histogram(app.UIAxes_4, app.rawData, 'FaceColor', 'blue', 'FaceAlpha', 0.7,'NumBins',50);
            
            % 计算统计量
            mean_val = mean(app.rawData);
            std_val = std(app.rawData);
            
            
            % 设置标题，显示统计信息
            title(app.UIAxes_4, sprintf('TOF distribution - Mean: %.2f, STD: %.2f', mean_val, std_val));
            
            % 获取温度
            resistance=fread(app.InstrumentObject,1,'float');
            temperature = pt1000_to_temperature(resistance, app.R0_calibration);
                
            app.TemperatureLabel_2.Text=num2str(temperature);
            
            app.idleLabel_2.Text='Idle';
            
            
        end

        % Button pushed function: BuildReferenceButton
        function BuildReferenceButtonPushed(app, event)
            app.ReferencesignalisnotavailableLabel.Text='Reference signal is not available';
            % send 5 trigger dTOF measurements
            fwrite(app.InstrumentObject,5,'uint8');
            % 参考信号获取flag
             % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<1)
                pause(1);
            end
            
            dumy=fread(app.InstrumentObject,1,'uint8');
            
            app.ReferencesignalisnotavailableLabel.Text='Reference signal is captured';
            
            
        end

        % Button pushed function: CaptureButton_3
        function CaptureButton_3Pushed(app, event)
            % capture continuesly
            app.Continue=1;
            tic
            
            while (app.Continue==1)
                
                % send 6 to trigger short tof measurements
                fwrite(app.InstrumentObject,6,'uint8');
                % retrieve the short tof data, and merge to A
                  % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<4)
                pause(1);
            end
                tof_meta=fread(app.InstrumentObject,1,'uint32')/2^16*250;
                
                
                       % wait enough temperature data
            while (app.InstrumentObject.BytesAvailable<4)
                pause(1);
            end
                % retrieve the temperature
                resistance=fread(app.InstrumentObject,1,'float');
                
%                   % wait enough temperature of pcb data
%             while (app.InstrumentObject.BytesAvailable<4)
%                 pause(1);
%             end
%                  % retrieve the temperature of pcb
%                 resistance_pcb=fread(app.InstrumentObject,1,'float');
                
                % compensate for tof
%                 tof_meta=tof_meta+app.TOF_over_R*(app.R_reference-resistance_pcb);
                % compensate for pt1000 in liquid
%                 resistance=resistance+app.Temperature_over_R*(app.R_reference-resistance_pcb);
%                 
                
                
                % form the data
                app.A=[app.A,tof_meta];
                % comversion for temperature
                temperature = pt1000_to_temperature(resistance, app.R0_calibration);
                app.B=[app.B,temperature];
                % retrieve the time
                app.time=[app.time,toc];
                
                x=app.time;
                cla(app.UIAxes_5)
                yyaxis(app.UIAxes_5,'left')
                plot(app.UIAxes_5,x,app.A)
                hold(app.UIAxes_5,'on')
                yyaxis(app.UIAxes_5,'right')
                plot(app.UIAxes_5,x,app.B)
                hold(app.UIAxes_5,'off')
                pause(0.1); drawnow;


            end


%                 numFloats = floor(length(rawData) / 4);
%                 % keyboard
%                 floatData =reshape( typecast(uint8(rawData(1:numFloats*4)), 'single'),double(configuration_register(3)),numFloats/double(configuration_register(3)));
%                 
%                 
%                 
%                 % Store in Buffer A using Circular Indexing
%                 numNewData = numFloats/double(configuration_register(3));
%                 endIndexA = app.bufferIndexA + numNewData - 1;
%                 
%                 
%                 
%                 if endIndexA <= app.bufferSizeA
%                     app.bufferA(app.row_index,app.bufferIndexA:endIndexA) = floatData;
%                 else
%                     splitPoint = app.bufferSizeA - app.bufferIndexA + 1;
%                     app.bufferA(app.row_index,app.bufferIndexA:end) = floatData(1:splitPoint);
%                     app.bufferA(app.row_index,1:numNewData - splitPoint) = floatData(splitPoint + 1:end);
%                 end
%                 
%                 % Update bufferIndexA (circular wrap-around)
%                 app.bufferIndexA = mod(app.bufferIndexA + numNewData - 1, app.bufferSizeA) + 1;
%                 
%                 % Select 1 point from bufferA to store in bufferB (Downsampling)
%                 app.bufferB(app.row_index,app.bufferIndexB) = floatData(:,end);  % Pick latest value
%                 % demostrate the conversion result in the text
%                 
%                 % Update bufferIndexB (circular wrap-around)
%                 app.bufferIndexB = mod(app.bufferIndexB, app.bufferSizeB) + 1;
%                 
%                 
%                 % Update plot
%                 for i=1:app.NumberOfChannels
%                     plot(app.UIAxes, app.bufferB(app.row_index(i),:));
%                     hold(app.UIAxes,'on')
%                     
%                 end
%                 
%                 hold(app.UIAxes,'off')
%             end
%             pause(0.001)
%             drawnow
%             
%         end
        
        
        end

        % Button pushed function: STOPButton_2
        function STOPButton_2Pushed(app, event)
            app.Continue=0;
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            % clear A B Time
            app.A=[];
            app.B=[];
            app.time=[];
        end

        % Button pushed function: DebugButton_2
        function DebugButton_2Pushed(app, event)
            keyboard;
        end

        % Button pushed function: autoscaleButton
        function autoscaleButtonPushed(app, event)
            axis(app.UIAxes_5, 'auto');
        end

        % Button pushed function: CaptureButton_4
        function CaptureButton_4Pushed(app, event)

            % trigger the ref. temp.  sensor
            fwrite(app.reference_temp_sensor,7,'uint8');
            % read the temperature
            ref_temp=fread(app.reference_temp_sensor,1,'float');
            app.refLabel.Text=num2str(ref_temp);
            
            % trigger the pt1000
             % send 6 to trigger short tof measurements
                fwrite(app.InstrumentObject,6,'uint8');
                % retrieve the short tof data, and merge to A
                  % wait enough tof data
            while (app.InstrumentObject.BytesAvailable<4)
                pause(1);
            end
                tof_meta=fread(app.InstrumentObject,1,'uint32')/2^16*250;
                
                
                       % wait enough temperature data
            while (app.InstrumentObject.BytesAvailable<4)
                pause(1);
            end
                % retrieve the temperature
                resistance=fread(app.InstrumentObject,1,'float');
                % convert to temperature
                temperature = pt1000_to_temperature(resistance, app.R0_calibration);
                
                app.PT1000Label.Text=num2str(temperature);


            %             % capture continuesly
%             app.Continue=1;
%             tic
%             
%             while (app.Continue==1)
%                 
%                 % send 6 to trigger short tof measurements
%                 fwrite(app.InstrumentObject,7,'uint8');
%                 % retrieve the short tof data, and merge to A
%                   % wait enough tof data
%             while (app.InstrumentObject.BytesAvailable<4)
%                 pause(1);
%             end
%                 tof_meta=fread(app.InstrumentObject,1,'uint32')/2^16*250;
%                 app.A=[app.A,tof_meta];
%                 
%                        % wait enough tof data
%             while (app.InstrumentObject.BytesAvailable<4)
%                 pause(1);
%             end
%                 % retrieve the temperature of liquid
%                 resistance=fread(app.InstrumentObject,1,'float');
%                 % temperature = pt1000_to_temperature(resistance, app.R0_calibration);
%                 app.B=[app.B,resistance];
%                 
%                  % wait enough tof data
%             while (app.InstrumentObject.BytesAvailable<4)
%                 pause(1);
%             end
%                  % retrieve the temperature of pcb
%                 resistance=fread(app.InstrumentObject,1,'float');
%                 app.C=[app.C,resistance];
%                 % retrieve the time
%                 app.time=[app.time,toc];
%                 
%                 x=app.time;
%                 cla(app.UIAxes_6)
%                 yyaxis(app.UIAxes_6,'left')
%                 plot(app.UIAxes_6,x,app.A)
%                 hold(app.UIAxes_6,'on')
%                 yyaxis(app.UIAxes_6,'right')
%                 plot(app.UIAxes_6,x,app.B)
%                 yyaxis(app.UIAxes_6,'right')
%                 plot(app.UIAxes_6,x,app.C)
%                 
%                 
%                 hold(app.UIAxes_6,'off')
%                 pause(0.1); drawnow;
%                 
%                 
%                 
%             end
        end

        % Button pushed function: STOPButton_3
        function STOPButton_3Pushed(app, event)
            app.Continue=0;
        end

        % Button pushed function: autoscaleButton_2
        function autoscaleButton_2Pushed(app, event)
            axis(app.UIAxes_6, 'auto');
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
             % clear A B C Time
            app.A=[];
            app.B=[];
            app.C=[];
            app.time=[];
        end

        % Button pushed function: FindsensorButton
        function FindsensorButtonPushed(app, event)
            % Find all available instruments
           ports=seriallist;
         % initial all buffers
%            app.bufferA = NaN(4,app.bufferSizeA);
%            app.bufferB = NaN(4,app.bufferSizeB);
           app.DeviceListDropDown_2.Items=ports; 
        end

        % Button pushed function: ConnecttoreftempsensorButton
        function ConnecttoreftempsensorButtonPushed(app, event)
             % Get the selected item from the dropdown
            
            obj=serial(app.DeviceListDropDown_2.Value,'BaudRate',4800);
            app.reference_temp_sensor=obj;
            
            set(app.reference_temp_sensor, 'InputBufferSize', 5000000);
           % Open the serial port
            fopen(app.reference_temp_sensor);
            app.WaitforconnectionLabel_2.Text='Connected';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoltageMeter = uifigure;
            app.VoltageMeter.Name = 'Ultrasonic TOF';
            app.VoltageMeter.CloseRequestFcn = createCallbackFcn(app, @VoltageMeterCloseRequest, true);
            app.TabGroup = uitabgroup(app.VoltageMeter);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.DeviceListDropDownLabel = uilabel(app.ConnectionTab);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.ConnectionTab);
            app.ConnectButton = uibutton(app.ConnectionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.WaitforconnectionLabel = uilabel(app.ConnectionTab);
            app.WaitforconnectionLabel.Text = 'Wait for connection';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.UIAxes2 = uiaxes(app.ConfigurationTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SendandreceivePanel = uipanel(app.ConfigurationTab);
            app.SendandreceivePanel.Title = 'Send and receive';
            app.Label_2 = uilabel(app.SendandreceivePanel);
            app.Label_2.Text = '1~8';
            app.NumberofpulsetosendEditFieldLabel = uilabel(app.SendandreceivePanel);
            app.NumberofpulsetosendEditFieldLabel.Text = 'Number of pulse to send';
            app.NumberofpulsetosendEditField = uieditfield(app.SendandreceivePanel, 'numeric');
            app.NumberofpulsetosendEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpulsetosendEditFieldValueChanged, true);
            app.NumberofpulsetosendEditField.Value = 8;
            app.NumberofpulsetoreceiveEditFieldLabel = uilabel(app.SendandreceivePanel);
            app.NumberofpulsetoreceiveEditFieldLabel.Text = 'Number of pulse to receive';
            app.NumberofpulsetoreceiveEditField = uieditfield(app.SendandreceivePanel, 'numeric');
            app.NumberofpulsetoreceiveEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpulsetoreceiveEditFieldValueChanged, true);
            app.NumberofpulsetoreceiveEditField.Value = 8;
            app.Label_3 = uilabel(app.SendandreceivePanel);
            app.Label_3.Text = '1~127';
            app.MaxofTimeofFlightusDropDownLabel = uilabel(app.SendandreceivePanel);
            app.MaxofTimeofFlightusDropDownLabel.Text = 'Max. of Time of Flight (us)';
            app.MaxofTimeofFlightusDropDown = uidropdown(app.SendandreceivePanel);
            app.MaxofTimeofFlightusDropDown.Items = {'64', '128', '256', '512', '1024', '2048', '4096', '', ''};
            app.MaxofTimeofFlightusDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', ''};
            app.MaxofTimeofFlightusDropDown.ValueChangedFcn = createCallbackFcn(app, @MaxofTimeofFlightusDropDownValueChanged, true);
            app.MaxofTimeofFlightusDropDown.Value = '2';
            app.TriggerPanel = uipanel(app.ConfigurationTab);
            app.TriggerPanel.Title = 'Trigger';
            app.GatingTimeusEditFieldLabel = uilabel(app.TriggerPanel);
            app.GatingTimeusEditFieldLabel.Text = 'Gating Time (us)';
            app.GatingTimeusEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.GatingTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @GatingTimeusEditFieldValueChanged, true);
            app.GatingTimeusEditField.Value = 30;
            app.TriggerThresholdmVEditFieldLabel = uilabel(app.TriggerPanel);
            app.TriggerThresholdmVEditFieldLabel.Text = 'Trigger Threshold (mV)';
            app.TriggerThresholdmVEditField = uieditfield(app.TriggerPanel, 'numeric');
            app.TriggerThresholdmVEditField.ValueChangedFcn = createCallbackFcn(app, @TriggerThresholdmVEditFieldValueChanged, true);
            app.mV62mVLabel = uilabel(app.TriggerPanel);
            app.mV62mVLabel.Text = '-64 mV~62 mV';
            app.usNN3216Label = uilabel(app.TriggerPanel);
            app.usNN3216Label.Text = '0.25 us *N, N=3~2^16';
            app.UploadParametersButton = uibutton(app.ConfigurationTab, 'push');
            app.UploadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UploadParametersButtonPushed, true);
            app.UploadParametersButton.Text = 'Upload Parameters';
            app.UIAxes2_2 = uiaxes(app.ConfigurationTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.DoneLabel = uilabel(app.ConfigurationTab);
            app.DoneLabel.Text = 'Done';
            app.iTOFMeasurementsTab = uitab(app.TabGroup);
            app.iTOFMeasurementsTab.Title = 'iTOF Measurements';
            app.CaptureButton_2 = uibutton(app.iTOFMeasurementsTab, 'push');
            app.CaptureButton_2.ButtonPushedFcn = createCallbackFcn(app, @CaptureButton_2Pushed, true);
            app.CaptureButton_2.Text = {'Capture'; ''};
            app.UIAxes_2 = uiaxes(app.iTOFMeasurementsTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'TOF (nS)')
            ylabel(app.UIAxes_2, 'Number of Samples')
            app.TemperatureLabel = uilabel(app.iTOFMeasurementsTab);
            app.TemperatureLabel.Text = 'Temperature';
            app.idleLabel = uilabel(app.iTOFMeasurementsTab);
            app.idleLabel.Text = 'idle';
            app.dTOFMeasurementsTab = uitab(app.TabGroup);
            app.dTOFMeasurementsTab.Title = 'dTOF Measurements';
            app.UIAxes_4 = uiaxes(app.dTOFMeasurementsTab);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, 'TOF (nS)')
            ylabel(app.UIAxes_4, 'Number of Samples')
            app.TemperatureLabel_2 = uilabel(app.dTOFMeasurementsTab);
            app.TemperatureLabel_2.Text = 'Temperature';
            app.dTOFmeasurementsButton = uibutton(app.dTOFMeasurementsTab, 'push');
            app.dTOFmeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @dTOFmeasurementsButtonPushed, true);
            app.dTOFmeasurementsButton.Text = 'dTOF measurements';
            app.BuildReferenceButton = uibutton(app.dTOFMeasurementsTab, 'push');
            app.BuildReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @BuildReferenceButtonPushed, true);
            app.BuildReferenceButton.Text = 'Build Reference';
            app.ReferencesignalisnotavailableLabel = uilabel(app.dTOFMeasurementsTab);
            app.ReferencesignalisnotavailableLabel.Text = 'Reference signal is not available';
            app.idleLabel_2 = uilabel(app.dTOFMeasurementsTab);
            app.idleLabel_2.Text = 'idle';
            app.CalibrationtemperatureTab = uitab(app.TabGroup);
            app.CalibrationtemperatureTab.Title = 'Calibration temperature';
            app.UIAxes_3 = uiaxes(app.CalibrationtemperatureTab);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'Temperature (Degree Celsius)')
            ylabel(app.UIAxes_3, 'Number of Samples')
            app.CalibrationButton = uibutton(app.CalibrationtemperatureTab, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.TOFvsTimeTab = uitab(app.TabGroup);
            app.TOFvsTimeTab.Title = 'TOF vs Time';
            app.UIAxes_5 = uiaxes(app.TOFvsTimeTab);
            title(app.UIAxes_5, 'TOF over time')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'iTOF (ns)')
            app.CaptureButton_3 = uibutton(app.TOFvsTimeTab, 'push');
            app.CaptureButton_3.ButtonPushedFcn = createCallbackFcn(app, @CaptureButton_3Pushed, true);
            app.CaptureButton_3.Text = {'Capture'; ''};
            app.STOPButton_2 = uibutton(app.TOFvsTimeTab, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_2Pushed, true);
            app.STOPButton_2.Text = 'STOP';
            app.ClearButton_2 = uibutton(app.TOFvsTimeTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = {'Clear'; ''};
            app.DebugButton_2 = uibutton(app.TOFvsTimeTab, 'push');
            app.DebugButton_2.ButtonPushedFcn = createCallbackFcn(app, @DebugButton_2Pushed, true);
            app.DebugButton_2.Text = 'Debug';
            app.autoscaleButton = uibutton(app.TOFvsTimeTab, 'push');
            app.autoscaleButton.ButtonPushedFcn = createCallbackFcn(app, @autoscaleButtonPushed, true);
            app.autoscaleButton.Text = 'auto scale';
            app.TemperaturecalibrationTab = uitab(app.TabGroup);
            app.TemperaturecalibrationTab.Title = 'Temperature calibration';
            app.CaptureButton_4 = uibutton(app.TemperaturecalibrationTab, 'push');
            app.CaptureButton_4.ButtonPushedFcn = createCallbackFcn(app, @CaptureButton_4Pushed, true);
            app.CaptureButton_4.Text = {'Capture'; ''};
            app.STOPButton_3 = uibutton(app.TemperaturecalibrationTab, 'push');
            app.STOPButton_3.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_3Pushed, true);
            app.STOPButton_3.Text = 'STOP';
            app.UIAxes_6 = uiaxes(app.TemperaturecalibrationTab);
            title(app.UIAxes_6, 'Reference sensor vs PT1000')
            xlabel(app.UIAxes_6, 'Reference sensor')
            ylabel(app.UIAxes_6, 'PT1000')
            app.autoscaleButton_2 = uibutton(app.TemperaturecalibrationTab, 'push');
            app.autoscaleButton_2.ButtonPushedFcn = createCallbackFcn(app, @autoscaleButton_2Pushed, true);
            app.autoscaleButton_2.Text = 'auto scale';
            app.ClearButton_3 = uibutton(app.TemperaturecalibrationTab, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = {'Clear'; ''};
            app.DeviceListDropDown_2Label = uilabel(app.TemperaturecalibrationTab);
            app.DeviceListDropDown_2Label.Text = 'Device List';
            app.DeviceListDropDown_2 = uidropdown(app.TemperaturecalibrationTab);
            app.ConnecttoreftempsensorButton = uibutton(app.TemperaturecalibrationTab, 'push');
            app.ConnecttoreftempsensorButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoreftempsensorButtonPushed, true);
            app.ConnecttoreftempsensorButton.Text = 'Connect to ref. temp sensor';
            app.FindsensorButton = uibutton(app.TemperaturecalibrationTab, 'push');
            app.FindsensorButton.ButtonPushedFcn = createCallbackFcn(app, @FindsensorButtonPushed, true);
            app.FindsensorButton.Text = 'Find sensor';
            app.WaitforconnectionLabel_2 = uilabel(app.TemperaturecalibrationTab);
            app.WaitforconnectionLabel_2.Text = 'Wait for connection';
            app.refLabel = uilabel(app.TemperaturecalibrationTab);
            app.refLabel.Text = 'ref.';
            app.PT1000Label = uilabel(app.TemperaturecalibrationTab);
            app.PT1000Label.Text = 'PT1000';
            app.CalibrationTDCTab = uitab(app.TabGroup);
            app.CalibrationTDCTab.Title = 'Calibration TDC';
            app.CalibrationofTDCPanel = uipanel(app.CalibrationTDCTab);
            app.CalibrationofTDCPanel.Title = 'Calibration of TDC';
            app.GatingTimeusEditField_4Label = uilabel(app.CalibrationofTDCPanel);
            app.GatingTimeusEditField_4Label.Text = 'Gating Time (us)';
            app.GatingTimeusEditField_4 = uieditfield(app.CalibrationofTDCPanel, 'numeric');
            app.GatingTimeusEditField_4.Value = 2;
            app.TriggerThresholdmVEditField_4Label = uilabel(app.CalibrationofTDCPanel);
            app.TriggerThresholdmVEditField_4Label.Text = 'Trigger Threshold (mV)';
            app.TriggerThresholdmVEditField_4 = uieditfield(app.CalibrationofTDCPanel, 'numeric');
            app.TriggerThresholdmVEditField_4.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = TOF

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoltageMeter)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoltageMeter)
        end
    end
end